classdef SerialMonitor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ExportDataButton              matlab.ui.control.Button
        TextArea                      matlab.ui.control.TextArea
        StartStopButton               matlab.ui.control.StateButton
        ConnectButton                 matlab.ui.control.StateButton
        AvailableDeviceDropDown       matlab.ui.control.DropDown
        AvailableDeviceDropDownLabel  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        serial % Serial port object
    end
    
    methods (Access = private,Static)
        
        function readData(src,~)
            data = readline(src);
            time = toc;
            src.UserData.Data(end+1) = str2double(data);
            src.UserData.Time(end+1) = time;
            src.UserData.Log.Value{end+1} = char(sprintf("%-15.2f%15.0f",src.UserData.Time(end),src.UserData.Data(end))); 
            if length(src.UserData.Data) > 3
                plot(src.UserData.Axes,src.UserData.Time(2:end),src.UserData.Data(2:end));
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AvailableDeviceDropDown.Items = serialportlist;
            app.TextArea.Value = sprintf("%-15s%15s","Time (s)","Value");
            app.TextArea.Visible = "off";
            app.UIAxes.Visible = "off";
            app.StartStopButton.Visible = "off";
            app.ExportDataButton.Visible = "off";
        end

        % Value changed function: ConnectButton
        function ConnectButtonValueChanged(app, event)
            if app.ConnectButton.Value
                % Connect to serial port and activate terminator listener
                app.serial = serialport(app.AvailableDeviceDropDown.Value, 9600);
                configureTerminator(app.serial,"CR/LF");

                % Setup app view
                app.TextArea.Visible = "on";
                app.UIAxes.Visible = "on";
                app.StartStopButton.Visible = "on";
                app.ExportDataButton.Visible = "on";
            else
                % Disconnect the connection to serial port
                delete(app.serial);
                
                % Reset app view
                startupFcn(app);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.ConnectButton.Value
                delete(app.serial)
            end
            delete(app)
        end

        % Value changed function: StartStopButton
        function StartStopButtonPushed(app, event)
            if app.StartStopButton.Value % Run
                flush(app.serial);
                app.serial.UserData = struct("Data",[],"Time",[],...
                    "Axes",app.UIAxes,"Log",app.TextArea);
                configureCallback(app.serial,"terminator",@app.readData);
                tic; % Start timer
            else % Stop run
                configureCallback(app.serial, "off");
            end
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            % Select the file
            [file,path,selected] = uiputfile('*.mat');
            app.UIFigure.Visible = "off";
            app.UIFigure.Visible = "on";

            % If an output file was selected
            if selected
                Time = seconds(app.serial.UserData.Time);
                Data = app.serial.UserData.Data;
                SerialData = timetable(Time',Data',VariableNames={"Time","Data"});
                save(fullfile(path,file),"SerialData");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Live Serial Data')
            xlabel(app.UIAxes, 'Timestamp')
            ylabel(app.UIAxes, 'Value')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.AvailableDeviceDropDownLabel = uilabel(app.UIFigure);
            app.AvailableDeviceDropDownLabel.Text = 'Available Device: ';
            app.AvailableDeviceDropDown = uidropdown(app.UIFigure);
            app.ConnectButton = uibutton(app.UIFigure, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Text = 'Connect';
            app.StartStopButton = uibutton(app.UIFigure, 'state');
            app.StartStopButton.ValueChangedFcn = createCallbackFcn(app, @StartStopButtonPushed, true);
            app.StartStopButton.Visible = 'off';
            app.StartStopButton.Text = 'Start/Stop';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Visible = 'off';
            app.ExportDataButton = uibutton(app.UIFigure, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Visible = 'off';
            app.ExportDataButton.Text = 'Export Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SerialMonitor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end